classdef demo2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        Hyperlink  matlab.ui.control.Hyperlink
        title      matlab.ui.control.Label
        equation   matlab.ui.control.Label
        size       matlab.ui.control.Label
        Minput     matlab.ui.control.NumericEditField
        MLabel     matlab.ui.control.Label
        Ninput     matlab.ui.control.NumericEditField
        NLabel     matlab.ui.control.Label
        adjLabel   matlab.ui.control.Label
        adjacent   matlab.ui.control.EditField
        initial    matlab.ui.container.ButtonGroup
        rand       matlab.ui.control.RadioButton
        down       matlab.ui.control.RadioButton
        up         matlab.ui.control.RadioButton
        start      matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Resize="off";
        end

        % Button pushed function: start
        function startPushed(app, event)

            try
                s = app.adjacent.Value;
                s = strrep(s, " ", "");
                s = split(s, ["(","（", ",","，", ")","）"]);
                P = zeros((length(s) - 1)/3, 2);
                for i = 1:length(P)
                    P(i, 1) = s(3*i-1);
                    P(i, 2) = s(3*i);
                end
                if any(isnan(P),"all")
                    error("NaN")
                end
                app.adjacent.FontColor="k";
            catch
                app.adjacent.FontColor="r";
            end
            N = app.Ninput.Value;
            M = app.Minput.Value;
            op = app.initial.SelectedObject.Text;
            demo3(N,M,P,op);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Ising Model';
            app.start = uibutton(app.UIFigure, 'push');
            app.start.ButtonPushedFcn = createCallbackFcn(app, @startPushed, true);
            app.start.Text = '生成';
            app.initial = uibuttongroup(app.UIFigure);
            app.initial.Title = '初始状态';
            app.up = uiradiobutton(app.initial);
            app.up.Text = '全up态';
            app.up.Value = true;
            app.down = uiradiobutton(app.initial);
            app.down.Text = '全down态';
            app.rand = uiradiobutton(app.initial);
            app.rand.Text = '随机状态';
            app.adjacent = uieditfield(app.UIFigure, 'text');
            app.adjacent.Value = '(-1,0)(1,0)(0,-1)(0,1)';
            app.adjLabel = uilabel(app.UIFigure);
            app.adjLabel.Text = '与(0,0)作用的原子';
            app.NLabel = uilabel(app.UIFigure);
            app.NLabel.Text = 'N';
            app.Ninput = uieditfield(app.UIFigure, 'numeric');
            app.Ninput.Limits = [0 1000];
            app.Ninput.ValueDisplayFormat = '%d';
            app.Ninput.Value = 32;
            app.MLabel = uilabel(app.UIFigure);
            app.MLabel.Text = 'M';
            app.Minput = uieditfield(app.UIFigure, 'numeric');
            app.Minput.Limits = [0 1000];
            app.Minput.ValueDisplayFormat = '%d';
            app.Minput.Value = 32;
            app.size = uilabel(app.UIFigure);
            app.size.Text = '网格大小';
            app.equation = uilabel(app.UIFigure);
            app.equation.Interpreter = 'latex';
            app.equation.Text = '$$H=-J\sum_{< \alpha ,\beta >}{S_{\alpha}S_{\beta}}-B\sum_{\alpha}{S_{\alpha}}$$';
            app.title = uilabel(app.UIFigure);
            app.title.Text = '二维Ising模型数值模拟';
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.URL = 'https://github.com/Dytchem/Ising-Model';
            app.Hyperlink.Text = '?';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = demo2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end