classdef demo3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        JMenu            matlab.ui.container.Menu
        BMenu            matlab.ui.container.Menu
        GridLayout       matlab.ui.container.GridLayout
        LeftPanel        matlab.ui.container.Panel
        isShow           matlab.ui.control.CheckBox
        isStop           matlab.ui.control.Button
        BLabel_3         matlab.ui.control.Label
        BLabel_2         matlab.ui.control.Label
        Binput           matlab.ui.control.NumericEditField
        BLabel           matlab.ui.control.Label
        Jinput           matlab.ui.control.NumericEditField
        pauseinput       matlab.ui.control.NumericEditField
        tinput           matlab.ui.control.NumericEditField
        startbutton      matlab.ui.control.Button
        JEditFieldLabel  matlab.ui.control.Label
        canvas           matlab.ui.control.UIAxes
        RightPanel       matlab.ui.container.Panel
        dt               matlab.ui.control.NumericEditField
        Label_9          matlab.ui.control.Label
        chioutput        matlab.ui.control.NumericEditField
        Label_8          matlab.ui.control.Label
        coutput          matlab.ui.control.NumericEditField
        Label_7          matlab.ui.control.Label
        moutput          matlab.ui.control.NumericEditField
        Label_6          matlab.ui.control.Label
        houtput          matlab.ui.control.NumericEditField
        Label_5          matlab.ui.control.Label
        now_m            matlab.ui.control.NumericEditField
        Label_3          matlab.ui.control.Label
        now_h            matlab.ui.control.NumericEditField
        Label_4          matlab.ui.control.Label
        Label_2          matlab.ui.control.Label
        Label            matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        N=NaN;
        M=NaN;
        NN=NaN;
        P=NaN;
        S=NaN;
        h=NaN;
        m=NaN;
        t_now=NaN;
        h_tot=NaN;
        m_tot=NaN;
        h2_tot=NaN;
        m2_tot=NaN;
        stopflag=false;
        stopflag2=false;
        % Timer = timer('ExecutionMode', 'fixedRate', ...
        %                       'Period', 1, ... % 每隔1秒触发
        %                       'TimerFcn', @(~,~) drawnow);
    end
    
    methods (Access = private)
        
        function draw(app)
            % disp(app.S)
            cla(app.canvas)
            hold(app.canvas,"on")
            px=nan(app.N*app.M,1);ip=1;
            py=px;nx=px;ny=px;in=1;
            for i=1:app.N
                for j=1:app.M
                    if app.S(i,j)==1
                        % scatter(app.canvas,i,j,"r+")
                        px(ip)=i;
                        py(ip)=j;
                        ip=ip+1;
                    else
                        % scatter(app.canvas,i,j,"b_")
                        nx(in)=i;
                        ny(in)=j;
                        in=in+1;
                    end
                end
            end
            % scatter(app.canvas,px,py,"r+")
            % scatter(app.canvas,nx,ny,"b_")
            plot(app.canvas,px,py,"r+")
            plot(app.canvas,nx,ny,"b_")
            hold(app.canvas,"off")
            % 更新数据
            try
                app.now_m.Value=app.m/app.NN;
                app.now_h.Value=app.h/app.NN;
                app.houtput.Value=app.h_tot/app.NN/app.t_now;
                app.moutput.Value=app.m_tot/app.NN/app.t_now;
                app.coutput.Value=app.h2_tot/app.NN^2/app.t_now-app.houtput.Value^2;
                app.chioutput.Value=app.m2_tot/app.NN^2/app.t_now-app.moutput.Value^2;
            catch

            end
            pause(0)
        end
        
        function heat(app,~)
            % start(app.Timer);
            function time_work()
                draw(app)
                drawnow()
            end
            if ~app.isShow.Value
                Timer1 = timer('ExecutionMode', 'fixedRate', ...
                              'Period', 1, ... % 每隔1秒触发
                              'TimerFcn', @(~,~) time_work());
                start(Timer1);
            end
            J=app.Jinput.Value;
            B=app.Binput.Value;
            if nargin==1
                t=app.tinput.Value;
            else
                t=1;
            end
            % 加速运算
            Smax = size(app.P,1); % 邻接最大自旋和
            TMP1 = exp(-2*(-1:1)'.*(J * (-Smax:Smax) + B)); % 概率
            TMP2 = -(-1:1)' .* (J * (-Smax:Smax)/2 + B); % 能量

            app.h_tot=0;
            app.m_tot=0;
            app.h2_tot=0;
            app.m2_tot=0;
            i = 1;
            app.t_now=0;
            while i<=t
                % fprintf("t = %d\n",i)
                if (nargin==1)
                    for x=1:app.N
                        for y=1:app.M
                            adjS1 = adjS(app,x, y);
                            r = TMP1(app.S(x, y)+2, adjS1+Smax+1);
                            if r > rand
                                app.S(x, y) = -app.S(x, y);
                            end
                        end
                    end
                end


                app.h=0;
                app.m=0;
                for x=1:app.N
                    for y=1:app.M
                        adjS1 = adjS(app,x, y);
                        app.m=app.m+app.S(x,y);
                        app.h=app.h+TMP2(app.S(x, y)+2, adjS1+Smax+1);
                    end
                end
                
                if mod(i,app.dt.Value)==0
                    app.h_tot=app.h_tot+app.h;
                    app.m_tot=app.m_tot+app.m;

                    app.h2_tot=app.h2_tot+app.h^2;
                    app.m2_tot=app.m2_tot+app.m^2;

                    app.t_now=app.t_now+1;
                end

                if app.isShow.Value
                    draw(app);
                    pause(app.pauseinput.Value);
                end

                % 检查是否终止
                if app.stopflag
                    app.stopflag=false;
                    break
                end

                i=i+1;
                app.tinput.Value=app.tinput.Value-1;
            end

            app.tinput.Value=t;

            draw(app);
            

            % stop(app.Timer);
            if ~app.isShow.Value
                stop(Timer1);
            end

        end
        
        function a=mod1(~,a,b)
            if a>b
                a=a-b;
            elseif a<1
                a=a+b;
            end
        end

        function s=adjS(app,x,y)
            s=0;
            for ii=1:size(app.P,1)
                s=s+app.S(mod1(app,x+app.P(ii,1),app.N),mod1(app,y+app.P(ii,2),app.M));
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, n, m, p, op)
            app.UIFigure.Resize="off";
            app.N=n;
            app.M=m;
            app.NN=n*m;
            app.UIFigure.Name="Ising Model "+n+" × "+m;
            app.P=p;
            
            if op=="全up态"
                app.S=ones(n,m);
            elseif op=="全down态"
                app.S=-ones(n,m);
            else
                app.S=ones(n,m);
                for i=1:numel(app.S)
                    if rand>0.5
                        app.S(i)=-1;
                    end
                end
            end
            
            axis(app.canvas,"equal");
            axis(app.canvas,[0,n+1,0,m+1]);
            xticks(app.canvas,1:n);
            yticks(app.canvas,1:m);
            xticklabels(app.canvas,[]);
            yticklabels(app.canvas,[]);

            heat(app,[])
            draw(app);
            app.tinput.Value=100;
            
        end

        % Button pushed function: startbutton
        function startbuttonButtonPushed(app, event)
            app.startbutton.Enable="off";
            app.tinput.Editable="off";
            app.Jinput.Editable="off";
            app.Binput.Editable="off";
            app.pauseinput.Editable="off";
            app.dt.Editable="off";
            app.isShow.Enable="off";
            app.isStop.Enable="on";
            pause(0)
            heat(app);
            % pause(1)
            app.startbutton.Enable="on";
            app.tinput.Editable="on";
            app.Jinput.Editable="on";
            app.Binput.Editable="on";
            app.pauseinput.Editable="on";
            app.dt.Editable="on";
            app.isStop.Enable="off";
            app.isShow.Enable="on";
            % fprintf("OK!\n")
        end

        % Button pushed function: isStop
        function isStopButtonPushed(app, event)
            app.stopflag=true;
            app.stopflag2=true;
        end

        % Value changed function: isShow
        function isShowValueChanged(app, event)
            if app.isShow.Value
                app.pauseinput.Enable="on";
            else
                app.pauseinput.Enable="off";
            end
            
        end

        % Menu selected function: JMenu
        function JMenuSelected(app, event)
            app.JMenu.Enable="off";
            app.BMenu.Enable="off";
            pause(0)
            % 弹出输入对话框，要求用户输入数组
            prompt = '请输入扫描范围:';
            title = 'J';
            defaultAnswer = '0:0.1:1';
            
            answer = inputdlg(prompt, title, 1, {defaultAnswer});
            
            if ~isempty(answer)
                % 将输入字符串转换为数组
                try
                    Js = eval(answer{1});
                
                    % 检查是否为有效数组
                    if isnumeric(Js) && isvector(Js)
                        % disp("OK")
                    else
                        uialert(app.UIFigure, '输入不是有效的数值数组！', '错误');
                        app.JMenu.Enable="on";
                        app.BMenu.Enable="on";
                        return
                    end
                catch
                    uialert(app.UIFigure, '输入格式有误，请输入有效的MATLAB数组格式！', '错误');
                    app.JMenu.Enable="on";
                    app.BMenu.Enable="on";
                    return
                end
            end

            app.stopflag2=false;
            try
                hs=nan(size(Js));
            catch
                app.JMenu.Enable="on";
                app.BMenu.Enable="on";
                return
            end
            ms=hs;
            cs=hs;
            chis=hs;

            for i=1:length(Js)
                app.Jinput.Value=Js(i);
                app.isShow.Value=false;
                startbuttonButtonPushed(app, []);
                draw(app)
                hs(i)=app.houtput.Value;
                ms(i)=app.moutput.Value;
                cs(i)=app.coutput.Value;
                chis(i)=app.chioutput.Value;
                if app.stopflag2
                    break
                end
            end

            figure;
            subplot(2,2,1);plot(Js,hs);xlabel("J");ylabel("h");legend("B = "+app.Binput.Value)
            subplot(2,2,2);plot(Js,ms);xlabel("J");ylabel("m");legend("B = "+app.Binput.Value)
            subplot(2,2,3);plot(Js,cs);xlabel("J");ylabel("c");legend("B = "+app.Binput.Value)
            subplot(2,2,4);plot(Js,chis);xlabel("J");ylabel("\chi");legend("B = "+app.Binput.Value)
            
            app.JMenu.Enable="on";
            app.BMenu.Enable="on";
        end

        % Menu selected function: BMenu
        function BMenuSelected(app, event)
            app.JMenu.Enable="off";
            app.BMenu.Enable="off";
            pause(0)
            % 弹出输入对话框，要求用户输入数组
            prompt = '请输入扫描范围:';
            title = 'J';
            defaultAnswer = '0:0.1:1';
            
            answer = inputdlg(prompt, title, 1, {defaultAnswer});
            
            if ~isempty(answer)
                % 将输入字符串转换为数组
                try
                    Bs = eval(answer{1});
                
                    % 检查是否为有效数组
                    if isnumeric(Bs) && isvector(Bs)
                        % disp("OK")
                    else
                        uialert(app.UIFigure, '输入不是有效的数值数组！', '错误');
                        app.JMenu.Enable="on";
                        app.BMenu.Enable="on";
                        return
                    end
                catch
                    uialert(app.UIFigure, '输入格式有误，请输入有效的MATLAB数组格式！', '错误');
                    app.JMenu.Enable="on";
                    app.BMenu.Enable="on";
                    return
                end
            end

            app.stopflag2=false;
            try
                hs=nan(size(Bs));
            catch
                app.JMenu.Enable="on";
                app.BMenu.Enable="on";
                return
            end
            ms=hs;
            cs=hs;
            chis=hs;

            for i=1:length(Bs)
                app.Binput.Value=Bs(i);
                app.isShow.Value=false;
                startbuttonButtonPushed(app, []);
                draw(app)
                hs(i)=app.houtput.Value;
                ms(i)=app.moutput.Value;
                cs(i)=app.coutput.Value;
                chis(i)=app.chioutput.Value;
                if app.stopflag2
                    break
                end
            end

            figure;
            subplot(2,2,1);plot(Bs,hs);xlabel("B");ylabel("h");legend("J = "+app.Jinput.Value)
            subplot(2,2,2);plot(Bs,ms);xlabel("B");ylabel("m");legend("J = "+app.Jinput.Value)
            subplot(2,2,3);plot(Bs,cs);xlabel("B");ylabel("c");legend("J = "+app.Jinput.Value)
            subplot(2,2,4);plot(Bs,chis);xlabel("B");ylabel("\chi");legend("J = "+app.Jinput.Value)

            app.JMenu.Enable="on";
            app.BMenu.Enable="on";
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {433, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.JMenu = uimenu(app.UIFigure);
            app.JMenu.MenuSelectedFcn = createCallbackFcn(app, @JMenuSelected, true);
            app.JMenu.Text = '扫描J';
            app.BMenu = uimenu(app.UIFigure);
            app.BMenu.MenuSelectedFcn = createCallbackFcn(app, @BMenuSelected, true);
            app.BMenu.Text = '扫描B';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.canvas = uiaxes(app.LeftPanel);
            zlabel(app.canvas, 'Z')
            app.canvas.XTick = [];
            app.canvas.YTick = [];
            app.canvas.ZTick = [];
            app.JEditFieldLabel = uilabel(app.LeftPanel);
            app.JEditFieldLabel.Text = 'J';
            app.startbutton = uibutton(app.LeftPanel, 'push');
            app.startbutton.ButtonPushedFcn = createCallbackFcn(app, @startbuttonButtonPushed, true);
            app.startbutton.Text = '热化';
            app.tinput = uieditfield(app.LeftPanel, 'numeric');
            app.tinput.Limits = [0 Inf];
            app.tinput.ValueDisplayFormat = '%d';
            app.tinput.Value = 100;
            app.pauseinput = uieditfield(app.LeftPanel, 'numeric');
            app.pauseinput.Limits = [0 Inf];
            app.Jinput = uieditfield(app.LeftPanel, 'numeric');
            app.BLabel = uilabel(app.LeftPanel);
            app.BLabel.Text = 'B';
            app.Binput = uieditfield(app.LeftPanel, 'numeric');
            app.BLabel_2 = uilabel(app.LeftPanel);
            app.BLabel_2.Text = '次数';
            app.BLabel_3 = uilabel(app.LeftPanel);
            app.BLabel_3.Text = '停顿';
            app.isStop = uibutton(app.LeftPanel, 'push');
            app.isStop.ButtonPushedFcn = createCallbackFcn(app, @isStopButtonPushed, true);
            app.isStop.Enable = 'off';
            app.isStop.Text = '终止';
            app.isShow = uicheckbox(app.LeftPanel);
            app.isShow.ValueChangedFcn = createCallbackFcn(app, @isShowValueChanged, true);
            app.isShow.Text = '显示每次改变';
            app.isShow.Value = true;
            app.RightPanel = uipanel(app.GridLayout);
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = '单次数据';
            app.Label_2 = uilabel(app.RightPanel);
            app.Label_2.Text = '多次数据';
            app.Label_4 = uilabel(app.RightPanel);
            app.Label_4.Interpreter = 'latex';
            app.Label_4.Text = '\textit{h}';
            app.now_h = uieditfield(app.RightPanel, 'numeric');
            app.now_h.Editable = 'off';
            app.Label_3 = uilabel(app.RightPanel);
            app.Label_3.Interpreter = 'latex';
            app.Label_3.Text = '\textit{m}';
            app.now_m = uieditfield(app.RightPanel, 'numeric');
            app.now_m.Editable = 'off';
            app.Label_5 = uilabel(app.RightPanel);
            app.Label_5.Interpreter = 'latex';
            app.Label_5.Text = '\textit{<h>}';
            app.houtput = uieditfield(app.RightPanel, 'numeric');
            app.houtput.Editable = 'off';
            app.Label_6 = uilabel(app.RightPanel);
            app.Label_6.Interpreter = 'latex';
            app.Label_6.Text = '\textit{<m>}';
            app.moutput = uieditfield(app.RightPanel, 'numeric');
            app.moutput.Editable = 'off';
            app.Label_7 = uilabel(app.RightPanel);
            app.Label_7.Interpreter = 'latex';
            app.Label_7.Text = '\textit{c}';
            app.coutput = uieditfield(app.RightPanel, 'numeric');
            app.coutput.Editable = 'off';
            app.Label_8 = uilabel(app.RightPanel);
            app.Label_8.Interpreter = 'latex';
            app.Label_8.Text = '\textit{\chi}';
            app.chioutput = uieditfield(app.RightPanel, 'numeric');
            app.chioutput.Editable = 'off';
            app.Label_9 = uilabel(app.RightPanel);
            app.Label_9.Text = '抽样间隔';
            app.dt = uieditfield(app.RightPanel, 'numeric');
            app.dt.Limits = [1 Inf];
            app.dt.ValueDisplayFormat = '%d';
            app.dt.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = demo3(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end